classdef Damper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Spoolr_minmSlider            matlab.ui.control.Slider
        Spoolr_minmSliderLabel       matlab.ui.control.Label
        GPaLabel                     matlab.ui.control.Label
        NmLabel                      matlab.ui.control.Label
        DampingcoefficeintDropDown   matlab.ui.control.DropDown
        DampingcoefficeintDropDownLabel  matlab.ui.control.Label
        SpringRateEditField          matlab.ui.control.NumericEditField
        SpringRateEditFieldLabel     matlab.ui.control.Label
        SpringIndexEditField         matlab.ui.control.NumericEditField
        SpringIndexEditFieldLabel    matlab.ui.control.Label
        NumberofturnsEditField       matlab.ui.control.NumericEditField
        NumberofturnsEditFieldLabel  matlab.ui.control.Label
        AntennaODmmSlider            matlab.ui.control.Slider
        AntennaODmmSliderLabel       matlab.ui.control.Label
        WiredmmSlider                matlab.ui.control.Slider
        WiredmmSliderLabel           matlab.ui.control.Label
        ShearModulusEditField        matlab.ui.control.NumericEditField
        ShearModulusEditFieldLabel   matlab.ui.control.Label
        RunSimButton                 matlab.ui.control.Button
        Spoolr_maxmSlider            matlab.ui.control.Slider
        Spoolr_maxmSliderLabel       matlab.ui.control.Label
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        K % Spring rate
        B % Damping coeff
        RMax % Spool Radius max
        RMin %Spool Radius min
        wd % wire d
        D % antenna dia
        G % Shear Modulus
        N % num turns
        %Property8 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SpringrateNmSliderValueChanged(app, event)

        end

        % Callback function
        function DampingbSliderValueChanged(app, event)

        end

        % Value changed function: Spoolr_maxmSlider
        function Spoolr_maxmSliderValueChanged(app, event)
            r = app.Spoolr_maxmSlider.Value;
            app.RMax = r;
        end

        % Button pushed function: RunSimButton
        function RunSimButtonPushed(app, event)
            wired = app.WiredmmSlider.Value;
            app.wd = wired/1000; %d

            antennaOD = app.AntennaODmmSlider.Value;
            app.D = antennaOD/1000; %D

            sh = app.ShearModulusEditField.Value;
            app.G = sh*10^9; %G

            num = app.NumberofturnsEditField.Value;
            app.N = num; %N

            rmax = app.Spoolr_maxmSlider.Value;
            app.RMax = rmax; %r

            rmin = app.Spoolr_minmSlider.Value;
            app.RMin = rmin;

            app.SpringIndexEditField.Value = app.D/app.wd; %spring index

            b = app.DampingcoefficeintDropDown.Value;
            app.B = str2num(b); %b

            app.SpringRateEditField.Value = (app.G*(app.wd^4))/(8*app.N*app.D^3);
            app.K = (app.G*(app.wd^4))/(8*app.N*app.D^3); %k


            clear workspace
            assignin('base','k', app.K)
            assignin('base','b',app.B)
            assignin('base','rMax',app.RMax)
            assignin('base','rMin',app.RMin)
            assignin('base','d',app.wd)
            assignin('base','D',app.D)
            assignin('base','G',app.G)
            assignin('base','N',app.N)
            open('Spring_model.slx')
            sim('Spring_model.slx')

            z = zeros(length(tout),1);
            %hold(app.UIAxes,'on')
            plot(app.UIAxes,tout,pos,tout,z,'LineWidth',1.5)
            %plot(app.UIAxes,tout,z,'LineWidth',0.5)
            %hold(app.UIAxes,'off')

            plot(app.UIAxes2,tout,w,'LineWidth',1.5)
            plot(app.UIAxes3,tout,Tmax,'LineWidth',1.5)
            plot(app.UIAxes4,tout,radius*1000,'LineWidth',1.5)
        end

        % Value changed function: WiredmmSlider
        function WiredmmSliderValueChanged(app, event)
            wired = app.WiredmmSlider.Value;
            app.wd = wired/1000;
        end

        % Value changed function: AntennaODmmSlider
        function AntennaODmmSliderValueChanged(app, event)
            antennaOD = app.AntennaODmmSlider.Value;
            app.D = antennaOD/1000;
        end

        % Value changed function: ShearModulusEditField
        function ShearModulusEditFieldValueChanged(app, event)
            sh = app.ShearModulusEditField.Value;
            app.G = sh*10^9;
        end

        % Value changed function: NumberofturnsEditField
        function NumberofturnsEditFieldValueChanged(app, event)
            num = app.NumberofturnsEditField.Value;
            app.N = num;
        end

        % Value changed function: SpringRateEditField
        function SpringRateEditFieldValueChanged(app, event)
            %kval = (app.G*(app.wd^4))/(8*app.N*app.D^3);
            %app.SpringRateEditField.Value = kval;
        end

        % Value changed function: SpringIndexEditField
        function SpringIndexEditFieldValueChanged(app, event)
            app.SpringIndexEditField.Value = app.D/app.wd;
        end

        % Drop down opening function: DampingcoefficeintDropDown
        function DampingcoefficeintDropDownOpening(app, event)
            b = app.DampingcoefficeintDropDown.Value;
            app.B = str2num(b);
        end

        % Value changed function: Spoolr_minmSlider
        function Spoolr_minmSliderValueChanged(app, event)
            rmin = app.Spoolr_minmSlider.Value;
            app.RMin = rmin
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Displacement')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Position (m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-1 90];
            app.UIAxes.YLim = [-0.55 0.05];
            app.UIAxes.XTick = [0 10 20 30 40 50 60 70 80 90];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Omega')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'W (RPM)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [-0.05 90];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Damping Torque')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Torque (Nm)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLim = [-0.05 90];
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Spool Radius')
            xlabel(app.UIAxes4, 'Time (s)')
            ylabel(app.UIAxes4, 'Radius (m)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XLim = [0 90];
            app.Spoolr_maxmSliderLabel = uilabel(app.UIFigure);
            app.Spoolr_maxmSliderLabel.Text = 'Spool r_max (m)';
            app.Spoolr_maxmSlider = uislider(app.UIFigure);
            app.Spoolr_maxmSlider.Limits = [0.006 0.02];
            app.Spoolr_maxmSlider.ValueChangedFcn = createCallbackFcn(app, @Spoolr_maxmSliderValueChanged, true);
            app.Spoolr_maxmSlider.Value = 0.01;
            app.RunSimButton = uibutton(app.UIFigure, 'push');
            app.RunSimButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimButtonPushed, true);
            app.RunSimButton.Text = 'Run Sim';
            app.ShearModulusEditFieldLabel = uilabel(app.UIFigure);
            app.ShearModulusEditFieldLabel.Text = 'Shear Modulus';
            app.ShearModulusEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShearModulusEditField.ValueChangedFcn = createCallbackFcn(app, @ShearModulusEditFieldValueChanged, true);
            app.ShearModulusEditField.Value = 43;
            app.WiredmmSliderLabel = uilabel(app.UIFigure);
            app.WiredmmSliderLabel.Text = 'Wire d (mm)';
            app.WiredmmSlider = uislider(app.UIFigure);
            app.WiredmmSlider.Limits = [2 7];
            app.WiredmmSlider.ValueChangedFcn = createCallbackFcn(app, @WiredmmSliderValueChanged, true);
            app.WiredmmSlider.Value = 4.75;
            app.AntennaODmmSliderLabel = uilabel(app.UIFigure);
            app.AntennaODmmSliderLabel.Text = 'Antenna OD (mm)';
            app.AntennaODmmSlider = uislider(app.UIFigure);
            app.AntennaODmmSlider.Limits = [40 150];
            app.AntennaODmmSlider.ValueChangedFcn = createCallbackFcn(app, @AntennaODmmSliderValueChanged, true);
            app.AntennaODmmSlider.Value = 98;
            app.NumberofturnsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofturnsEditFieldLabel.Text = 'Number of turns';
            app.NumberofturnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofturnsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofturnsEditFieldValueChanged, true);
            app.NumberofturnsEditField.Value = 8;
            app.SpringIndexEditFieldLabel = uilabel(app.UIFigure);
            app.SpringIndexEditFieldLabel.Text = 'Spring Index';
            app.SpringIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpringIndexEditField.ValueChangedFcn = createCallbackFcn(app, @SpringIndexEditFieldValueChanged, true);
            app.SpringIndexEditField.Editable = 'off';
            app.SpringRateEditFieldLabel = uilabel(app.UIFigure);
            app.SpringRateEditFieldLabel.Text = 'Spring Rate';
            app.SpringRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpringRateEditField.ValueChangedFcn = createCallbackFcn(app, @SpringRateEditFieldValueChanged, true);
            app.SpringRateEditField.Editable = 'off';
            app.DampingcoefficeintDropDownLabel = uilabel(app.UIFigure);
            app.DampingcoefficeintDropDownLabel.Text = 'Damping coefficeint';
            app.DampingcoefficeintDropDown = uidropdown(app.UIFigure);
            app.DampingcoefficeintDropDown.Items = {'0.46', '0.92', '1.38', '1.84'};
            app.DampingcoefficeintDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DampingcoefficeintDropDownOpening, true);
            app.DampingcoefficeintDropDown.Value = '0.92';
            app.NmLabel = uilabel(app.UIFigure);
            app.NmLabel.Text = 'N/m';
            app.GPaLabel = uilabel(app.UIFigure);
            app.GPaLabel.Text = 'GPa';
            app.Spoolr_minmSliderLabel = uilabel(app.UIFigure);
            app.Spoolr_minmSliderLabel.Text = 'Spool r_min (m)';
            app.Spoolr_minmSlider = uislider(app.UIFigure);
            app.Spoolr_minmSlider.Limits = [0.002 0.02];
            app.Spoolr_minmSlider.ValueChangedFcn = createCallbackFcn(app, @Spoolr_minmSliderValueChanged, true);
            app.Spoolr_minmSlider.Value = 0.01;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Damper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end